classdef GUI_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UploadVideoButton              matlab.ui.control.Button
        FrameSliderLabel               matlab.ui.control.Label
        FrameSlider                    matlab.ui.control.Slider
        SaveProcessedVideoButton       matlab.ui.control.Button
        DisplayBoundaryLayerButton     matlab.ui.control.StateButton
        InstantaneousVelocityDisplayButton  matlab.ui.control.Button
        AverageVelocityDisplayButton   matlab.ui.control.Button
        MaxSpreadRadiusDisplayButton   matlab.ui.control.Button
        SaveAnalyticsButton            matlab.ui.control.Button
        JetSpeedDisplayButton          matlab.ui.control.Button
        InstantVelocityTxt             matlab.ui.control.EditField
        AverageVelocityText            matlab.ui.control.EditField
        MaxRadiusText                  matlab.ui.control.EditField
        JetSpeedTxt                    matlab.ui.control.EditField
        DropletFormationDisplayButton  matlab.ui.control.Button
        DropletFormationTxt            matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: UIFigure, UploadVideoButton
        function UploadVideoButtonPushed(app, event)
            [picname,picpath] = uigetfile('*.png'); % this asks the user for a file input
            fullname = fullfile(picpath,picname); % this concatenates the path and file name of the input file
            imshow(fullname,'Parent',app.UIAxes); % this displays the input file in the original video axes
        end

        % Button pushed function: InstantaneousVelocityDisplayButton
        function InstantaneousVelocityDisplayButtonPushed(app, event)
            app.InstantVelocityTxt.Visible = 'on'; % this displays the text box for instant velocity 
        end

        % Button pushed function: AverageVelocityDisplayButton
        function AverageVelocityDisplayButtonPushed(app, event)
            app.AverageVelocityText.Visible = 'on'; % this displays the text box for average velocity
        end

        % Button pushed function: MaxSpreadRadiusDisplayButton
        function MaxSpreadRadiusDisplayButtonPushed(app, event)
            app.MaxRadiusText.Visible = 'on'; % this displays the text box for max spread radius
        end

        % Button pushed function: JetSpeedDisplayButton
        function JetSpeedDisplayButtonPushed(app, event)
            app.JetSpeedTxt.Visible = 'on'; % this displays the text box for jet speed
        end

        % Button pushed function: DropletFormationDisplayButton
        function DropletFormationDisplayButtonPushed(app, event)
            app.DropletFormationTxt.Visible = 'on'; % this displays the text box for droplet formation
        end

        % Button pushed function: SaveAnalyticsButton
        function SaveAnalyticsButtonPushed(app, event)
            % Create two columns of data
            A  = rand(10,1);
            B = rand(10,1);
            % Create a table with the data and variable names
            T = table(A, B, 'VariableNames', { 'A', 'B'} )
            % Write data to text file
            writetable(T, uiputfile('*.txt'))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UploadVideoButtonPushed, true);
            app.UploadVideoButton = uibutton(app.UIFigure, 'push');
            app.UploadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideoButtonPushed, true);
            app.UploadVideoButton.Text = 'Upload Video';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [0 15];
            app.FrameSlider.MajorTicks = [5 10 15];
            app.FrameSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.SaveProcessedVideoButton = uibutton(app.UIFigure, 'push');
            app.SaveProcessedVideoButton.Text = 'Save Processed Video ';
            app.DisplayBoundaryLayerButton = uibutton(app.UIFigure, 'state');
            app.DisplayBoundaryLayerButton.Text = {'Display Boundary'; 'Layer'};
            app.InstantaneousVelocityDisplayButton = uibutton(app.UIFigure, 'push');
            app.InstantaneousVelocityDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @InstantaneousVelocityDisplayButtonPushed, true);
            app.InstantaneousVelocityDisplayButton.Text = 'Instantaneous Velocity Display';
            app.AverageVelocityDisplayButton = uibutton(app.UIFigure, 'push');
            app.AverageVelocityDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @AverageVelocityDisplayButtonPushed, true);
            app.AverageVelocityDisplayButton.Text = 'Average Velocity Display';
            app.MaxSpreadRadiusDisplayButton = uibutton(app.UIFigure, 'push');
            app.MaxSpreadRadiusDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @MaxSpreadRadiusDisplayButtonPushed, true);
            app.MaxSpreadRadiusDisplayButton.Text = 'Max Spread Radius Display';
            app.SaveAnalyticsButton = uibutton(app.UIFigure, 'push');
            app.SaveAnalyticsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalyticsButtonPushed, true);
            app.SaveAnalyticsButton.Text = 'Save Analytics';
            app.JetSpeedDisplayButton = uibutton(app.UIFigure, 'push');
            app.JetSpeedDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @JetSpeedDisplayButtonPushed, true);
            app.JetSpeedDisplayButton.Text = 'Jet Speed Display';
            app.InstantVelocityTxt = uieditfield(app.UIFigure, 'text');
            app.InstantVelocityTxt.Editable = 'off';
            app.InstantVelocityTxt.Visible = 'off';
            app.AverageVelocityText = uieditfield(app.UIFigure, 'text');
            app.AverageVelocityText.Editable = 'off';
            app.AverageVelocityText.Visible = 'off';
            app.MaxRadiusText = uieditfield(app.UIFigure, 'text');
            app.MaxRadiusText.Editable = 'off';
            app.MaxRadiusText.Visible = 'off';
            app.JetSpeedTxt = uieditfield(app.UIFigure, 'text');
            app.JetSpeedTxt.Editable = 'off';
            app.JetSpeedTxt.Visible = 'off';
            app.DropletFormationDisplayButton = uibutton(app.UIFigure, 'push');
            app.DropletFormationDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DropletFormationDisplayButtonPushed, true);
            app.DropletFormationDisplayButton.Text = 'Droplet Formation Display';
            app.DropletFormationTxt = uieditfield(app.UIFigure, 'text');
            app.DropletFormationTxt.Editable = 'off';
            app.DropletFormationTxt.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Video')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Processed Video')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end